classdef passive_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_3                        matlab.ui.container.Panel
        Panel_13                       matlab.ui.container.Panel
        Image10                        matlab.ui.control.Image
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel_6  matlab.ui.control.Label
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel_5  matlab.ui.control.Label
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel_4  matlab.ui.control.Label
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel_3  matlab.ui.control.Label
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel_2  matlab.ui.control.Label
        ProjectNamePassivefilterswithInputtakenfromUsersandLabel  matlab.ui.control.Label
        Image2_6                       matlab.ui.control.Image
        Image8                         matlab.ui.control.Image
        Panel_12                       matlab.ui.container.Panel
        FilltersDropDown_cal_panel     matlab.ui.control.DropDown
        Panel_sinusoidinput_3          matlab.ui.container.Panel
        outsidepassbandLabel           matlab.ui.control.Label
        TimeConstantEditField          matlab.ui.control.EditField
        TimeConstantEditFieldLabel     matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.EditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        AttenuationRateEditField       matlab.ui.control.EditField
        AttenuationRateEditFieldLabel  matlab.ui.control.Label
        QualityfactorEditField         matlab.ui.control.EditField
        QualityfactorEditFieldLabel    matlab.ui.control.Label
        BandwidthEditField             matlab.ui.control.EditField
        BandwidthEditFieldLabel        matlab.ui.control.Label
        Image2_5                       matlab.ui.control.Image
        HomeButton_sinusoid_5          matlab.ui.control.Button
        ENDButton                      matlab.ui.control.Button
        Panel_11                       matlab.ui.container.Panel
        FilltersDropDown_sch_panel     matlab.ui.control.DropDown
        Panel_sinusoidinput_2          matlab.ui.container.Panel
        Image2_3                       matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        CEditField_2                   matlab.ui.control.NumericEditField
        CEditField_2Label              matlab.ui.control.Label
        LEditField_2                   matlab.ui.control.NumericEditField
        LEditField_2Label              matlab.ui.control.Label
        REditField_2                   matlab.ui.control.NumericEditField
        REditField_2Label              matlab.ui.control.Label
        HomeButton_sinusoid_4          matlab.ui.control.Button
        Panel_sinusoidinput_freqdomain_panel  matlab.ui.container.Panel
        Image2_4                       matlab.ui.control.Image
        HomeButton_sinusoid_3          matlab.ui.control.Button
        OutputLabel_2                  matlab.ui.control.Label
        InputLabel_2                   matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Panel_7                        matlab.ui.container.Panel
        XitLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FilltersDropDown_inoutput_panel  matlab.ui.control.DropDown
        Panel_sinusoidinput            matlab.ui.container.Panel
        Image2_2                       matlab.ui.control.Image
        HomeButton_sinusoid            matlab.ui.control.Button
        OutputLabel                    matlab.ui.control.Label
        InputLabel                     matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        Panel_8                        matlab.ui.container.Panel
        Panel_10                       matlab.ui.container.Panel
        CenterfrequencyEditField       matlab.ui.control.NumericEditField
        CenterfrequencyEditFieldLabel  matlab.ui.control.Label
        HighercutofffreqEditField_2    matlab.ui.control.NumericEditField
        HighercutofffreqEditField_2Label  matlab.ui.control.Label
        lowercutofffreqEditField_2     matlab.ui.control.NumericEditField
        lowercutofffreqEditField_2Label  matlab.ui.control.Label
        frequencyofprincipalharmonicsEditField  matlab.ui.control.NumericEditField
        frequencyofprincipalharmonicsEditFieldLabel  matlab.ui.control.Label
        valuesEditField                matlab.ui.control.EditField
        valuesEditFieldLabel           matlab.ui.control.Label
        CutofffrequencyEditField_2     matlab.ui.control.NumericEditField
        CutofffrequencyEditField_2Label  matlab.ui.control.Label
        finalEditField                 matlab.ui.control.NumericEditField
        finalEditFieldLabel            matlab.ui.control.Label
        firstEditField                 matlab.ui.control.NumericEditField
        firstEditFieldLabel            matlab.ui.control.Label
        incrementSpinner               matlab.ui.control.Spinner
        incrementSpinnerLabel          matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        HarmonicsLabel                 matlab.ui.control.Label
        phaseangleEditField            matlab.ui.control.NumericEditField
        phaseangleEditFieldLabel       matlab.ui.control.Label
        amplitudeEditField             matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        InputparametersLabel           matlab.ui.control.Label
        Panel_bodeplot                 matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        FilltersDropDown_bode_panel    matlab.ui.control.DropDown
        Panel_5                        matlab.ui.container.Panel
        MagnitudeplotDBLabel           matlab.ui.control.Label
        UIAxes_MDB                     matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        PhaseplotLabel                 matlab.ui.control.Label
        UIAxes_P                       matlab.ui.control.UIAxes
        Panel_6                        matlab.ui.container.Panel
        MagnitudeplotLabel             matlab.ui.control.Label
        UIAxes_M                       matlab.ui.control.UIAxes
        HomeButton_bode                matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        REditField                     matlab.ui.control.NumericEditField
        REditFieldLabel                matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Panel_9                        matlab.ui.container.Panel
        lowercutofffreqEditField       matlab.ui.control.NumericEditField
        lowercutofffreqEditField_3Label  matlab.ui.control.Label
        HzLabel_3                      matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        HighercutofffreqEditField      matlab.ui.control.NumericEditField
        HighercutofffreqEditField_3Label  matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        CutofffrequencyEditField       matlab.ui.control.NumericEditField
        CutofffrequencyEditFieldLabel  matlab.ui.control.Label
        FilltersDropDown               matlab.ui.control.DropDown
        FilterTypeSelectionLabel       matlab.ui.control.Label
        inputandoutputsignalButton     matlab.ui.control.Button
        DifferentcalculationsButton    matlab.ui.control.Button
        BodeplotPhaseplotsButton       matlab.ui.control.Button
        SchematicsButton               matlab.ui.control.Button
        CEditField                     matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        LEditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                matlab.ui.control.Label
        Image9                         matlab.ui.control.Image
    end


    methods (Access = public)
        
        function imageHoverEffect(app)

             % Get current mouse position relative to UIFigure
    mousePos = app.UIFigure.CurrentPoint;

    % Get the absolute path to app's folder
    appFolder = fileparts(mfilename('fullpath'));

    % Get image position [x y width height]
    imgPos = app.Image2.Position;

    % Check if mouse is over image
    isOver = mousePos(1) >= imgPos(1) && ...
             mousePos(1) <= (imgPos(1) + imgPos(3)) && ...
             mousePos(2) >= imgPos(2) && ...
             mousePos(2) <= (imgPos(2) + imgPos(4));

    % Change image appearance based on hover
    if isOver
        app.Image2.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');  % Lighter or highlighted image
        app.Image2_2.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');
        app.Image2_3.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');
        app.Image2_4.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');
        app.Image2_5.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');
        app.Image2_6.ImageSource = fullfile(appFolder, 'resources', 'home_light.png');
    else
        app.Image2.ImageSource =fullfile(appFolder, 'resources', 'home.png');        % Normal image
        app.Image2_2.ImageSource =fullfile(appFolder, 'resources', 'home.png');
        app.Image2_3.ImageSource =fullfile(appFolder, 'resources', 'home.png');
        app.Image2_4.ImageSource =fullfile(appFolder, 'resources', 'home.png');
        app.Image2_5.ImageSource =fullfile(appFolder, 'resources', 'home.png');
        app.Image2_6.ImageSource =fullfile(appFolder, 'resources', 'home.png');
    end

    imgPos1 = app.Image6.Position;

    % Check if mouse is over image
    isOver = mousePos(1) >= imgPos1(1) && ...
             mousePos(1) <= (imgPos1(1) + imgPos1(3)) && ...
             mousePos(2) >= imgPos1(2) && ...
             mousePos(2) <= (imgPos1(2) + imgPos1(4));
    if isOver
        app.Image6.ImageSource = fullfile(appFolder,'resources', 'info_light.png');  % Lighter or highlighted image
        
        
    else
        app.Image6.ImageSource =fullfile(appFolder,'resources', 'info.png');        % Normal image
 
    end 

    imgPos1 = app.Image7.Position

    % Check if mouse is over image
    isOver = mousePos(1) >= imgPos1(1) && ...
             mousePos(1) <= (imgPos1(1) + imgPos1(3)) && ...
             mousePos(2) >= imgPos1(2) && ...
             mousePos(2) <= (imgPos1(2) + imgPos1(4));
    if isOver
        app.Image7.ImageSource = fullfile(appFolder,'resources', 'reset_light.png');  % Lighter or highlighted image
  
    else
        app.Image7.ImageSource =fullfile(appFolder,'resources', 'reset.png');        % Normal image
    end 
     
    imgPos1 = app.Image10.Position

    % Check if mouse is over image
    isOver = mousePos(1) >= imgPos1(1) && ...
             mousePos(1) <= (imgPos1(1) + imgPos1(3)) && ...
             mousePos(2) >= imgPos1(2) && ...
             mousePos(2) <= (imgPos1(2) + imgPos1(4));
    if isOver
        app.Image10.ImageSource = fullfile(appFolder,'resources', 'github-sign_inverted.png');  % Lighter or highlighted image
  
    else
        app.Image10.ImageSource =fullfile(appFolder,'resources', 'github-sign.png');        % Normal image
    end 
    
end
  end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Panel_7.Position=[0 0 871 562];
            app.Panel_7.Visible="off";
            app.Panel_bodeplot.Position=[0 0 871 562];
            app.Panel_bodeplot.Visible="off";
            app.Panel_sinusoidinput_freqdomain_panel.Visible="off";
            app.Panel_sinusoidinput_freqdomain_panel.Parent=app.Panel_7;
            app.Panel_sinusoidinput_freqdomain_panel.Position=app.Panel_sinusoidinput.Position;
            app.Panel_11.Position=[0 0 871 562];
            app.Panel_11.Visible='off';
            app.Panel_12.Position=[0 0 871 562];
            app.Panel_12.Visible='off';
            app.Panel_13.Position=[0 0 871 562];
            app.Panel_13.Visible='off';
 
            r=app.REditField.Value;
            app.CEditField.Value=1/(2*pi*r*app.CutofffrequencyEditField.Value);
                        app.LEditField.Limits=[0 inf];
                        app.REditField.Limits=[eps inf];
                        app.CEditField.Limits=[eps inf];
                        app.LEditField.Editable = false;
                        app.REditField.Editable = true;
                        app.CEditField.Editable = true;

            app.UIFigure.Resize = 'off';

            app.UIFigure.WindowButtonMotionFcn = @(src, event) imageHoverEffect(app);%mouse hovering tracking for home button

        end

        % Button pushed function: BodeplotPhaseplotsButton
        function BodeplotPhaseplotsButtonPushed(app, event)
            app.Panel_2.Visible="off";
            app.Panel_bodeplot.Visible="on";
            value=app.FilltersDropDown.Value;
            cla(app.UIAxes_M);
            cla(app.UIAxes_MDB);
            switch value
                case 'Series RC Highpass filter'
                    
                r=app.REditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*r*c);
                fi=0:fc/100:fc*5;
                wi=2*pi*fi;
                
                zi=(i*wi*r*c)./(1+i*wi*r*c);
                m=abs(zi);
                u=double(fi-fc>=0);
                
             
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');

                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, fc, 'r:', ...
    'Label', sprintf('%.1f Hz', fc), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 10, ...
    'LineWidth', 1.5);
            
            case 'Series RC Lowpass filter'
                 
                r=app.REditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*r*c);
                fi=0:fc/100:fc*10;
                wi=2*pi*fi;
                
                zi=1./(1+i*wi*r*c);
                m=abs(zi);
                u=double(fi>=0)-double(fi-fc>=0);

                
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, fc, 'r:', ...
    'Label', sprintf('%.1f Hz', fc), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 10, ...
    'LineWidth', 1.5);
            
          case 'Series RL Highpass filter'
                  
                r=app.REditField.Value;
                l=app.LEditField.Value;
                    fc=r/(2*pi*l);
                fi=0:fc/100:fc*5;
                wi=2*pi*fi;
                
                zi=i*wi*l./(r+i*wi*l);
                m=abs(zi);
                u=double(fi-fc>=0);

                
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, fc, 'r:', ...
    'Label', sprintf('%.1f Hz', fc), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 10, ...
    'LineWidth', 1.5);

        case 'Series RL Lowpass filter'
                    
                r=app.REditField.Value;
                l=app.LEditField.Value;
                    fc=r/(2*pi*l);
                fi=0:fc/100:fc*10;
                wi=2*pi*fi;
                
                zi=r./(r+i*wi*l);
                m=abs(zi);
                u=double(fi>=0)-double(fi-fc>=0);

              
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, fc, 'r:', ...
    'Label', sprintf('%.1f Hz', fc), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 10, ...
    'LineWidth', 1.5);

        case 'Series RLC Bandpass filter'
                f1=app.lowercutofffreqEditField.Value;
                f2=app.HighercutofffreqEditField.Value;
               
             
                r=app.REditField.Value;
                l=app.LEditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*sqrt(l*c));%fc= for RLC band filter it's centerfreq.
                a=5/100;
                span2=(sqrt(1-a^2)*r*c+sqrt(r*r*(c^2)*(1-a^2)+4*(a^2)*l*c ...
                    ))/(4*pi*a*l*c);
                span1=(-sqrt(1-a^2)*r*c+sqrt(r*r*(c^2)*(1-a^2)+4*(a^2)*l*c ...
                    ))/(4*pi*a*l*c);
                fi=span1:fc/100:span2;
                wi=2*pi*fi;
                zi=r./(r+1i*(wi*l-1./(wi*c)));
                m=abs(zi);
                u=double(fi-f1>=0)-double(fi-f2>=0);

               
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, f1, 'r:', ...
    'Label', sprintf('%.1f Hz', f1), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);
                xline(app.UIAxes_MDB, f2, 'r:', ...
    'Label', sprintf('%.1f Hz', f2), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);

        case 'Series RLC Bandstop filter'
                f1=app.lowercutofffreqEditField.Value;
                f2=app.HighercutofffreqEditField.Value;
             
                  
                r=app.REditField.Value;
                l=app.LEditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*sqrt(l*c));%fc= for RLC band filter it's centerfreq.
                a=5/100;
                span2=(sqrt(1-a^2)*r*c+sqrt(r*r*(c^2)*(1-a^2)+4*(a^2)*l*c ...
                    ))/(4*pi*a*l*c);
                span1=(-sqrt(1-a^2)*r*c+sqrt(r*r*(c^2)*(1-a^2)+4*(a^2)*l*c ...
                    ))/(4*pi*a*l*c);
                fi=span1:fc/100:span2;
                wi=2*pi*fi;
                
                zi=1i*(wi*l-1./(wi*c))./(r+1i*(wi*l-1./(wi*c)));
                m=abs(zi);
                u=double(-fi+f1>=0)+double(fi-f2>=0);

 
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, f1, 'r:', ...
    'Label', sprintf('%.1f Hz', f1), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);
                xline(app.UIAxes_MDB, f2, 'r:', ...
    'Label', sprintf('%.1f Hz', f2), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);

        case 'Parallel RLC Bandpass filter' 
                f1=app.lowercutofffreqEditField.Value;
                f2=app.HighercutofffreqEditField.Value;
                
                    
                r=app.REditField.Value;
                l=app.LEditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*sqrt(l*c));%fc= for RLC band filter it's centerfreq.
                a=5/100;
                span2=(l*sqrt(1-a^2)+sqrt((l^2)*(1-a^2)+4*a*r* ...
                    (a*l*c*r)))/(4*pi*(a*l*c*r));
                span1=(-l*sqrt(1-a^2)+sqrt((l^2)*(1-a^2)+4*a*r* ...
                    (a*l*c*r)))/(4*pi*(a*l*c*r));
                fi=span1:fc/100:span2;
                wi=2*pi*fi;
                zi=(1/r)./((1/r)+1i*(wi*c-1./(wi*l)));
                m=abs(zi);
                u=double(fi-f1>=0)-double(fi-f2>=0);

                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, f1, 'r:', ...
    'Label', sprintf('%.1f Hz', f1), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);
                xline(app.UIAxes_MDB, f2, 'r:', ...
    'Label', sprintf('%.1f Hz', f2), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);

            case 'Parallel RLC Bandstop filter' 
                f1=app.lowercutofffreqEditField.Value;
                f2=app.HighercutofffreqEditField.Value;

                    
                r=app.REditField.Value;
                l=app.LEditField.Value;
                c=app.CEditField.Value;
                    fc=1/(2*pi*sqrt(l*c));%fc= for RLC band filter it's centerfreq.
                a=5/100;
                span2=(l*(1-a^2)+sqrt((l^2)*(1-a^2)^2+4*a*r* ...
                    (a*l*c*r)))/(4*pi*(a*l*c*r));
                span1=(l*(a^2-1)+sqrt((l^2)*(1-a^2)^2+4*a*r* ...
                    (a*l*c*r)))/(4*pi*(a*l*c*r));
                fi=span1:fc/100:span2;
                wi=2*pi*fi;
                zi=1i*(wi*c-1./(wi*l))./((1/r)+1i*(wi*c-1./(wi*l)));
                m=abs(zi);
                u=double(-fi+f1>=0)+double(fi-f2>=0);

                
                plot(app.UIAxes_M,fi,m);
                grid(app.UIAxes_M,'on');
                hold(app.UIAxes_M,'on');
                plot(app.UIAxes_M,fi,u,'r:');

                p=angle(zi);
                plot(app.UIAxes_P,fi,p);
                grid(app.UIAxes_P,'on');
                db=20*log10(m);
                semilogx(app.UIAxes_MDB,fi,db);
                hold(app.UIAxes_MDB,'on');
                grid(app.UIAxes_MDB,'on');
                xline(app.UIAxes_MDB, f1, 'r:', ...
    'Label', sprintf('%.1f Hz', f1), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);
                xline(app.UIAxes_MDB, f2, 'r:', ...
    'Label', sprintf('%.1f Hz', f2), ...
    'LabelOrientation', 'horizontal', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontSize', 5, ...
    'LineWidth', 1.5);

            end
        end

        % Value changed function: FilltersDropDown, REditField
        function FilltersDropDownValueChanged(app, event)
            value = app.FilltersDropDown.Value;
          app.FilltersDropDown_bode_panel.Value=value;
          app.FilltersDropDown_inoutput_panel.Value=value;
          app.FilltersDropDown_sch_panel.Value=value;
          app.FilltersDropDown_cal_panel.Value=value;
                    if strcmp(value, 'Series RC Highpass filter') || strcmp(value, 'Series RC Lowpass filter')
                        app.Panel_9.Visible='off';
                        
                        app.LEditField.Limits=[0 inf];
                        app.REditField.Limits=[eps inf];
                        app.CEditField.Limits=[eps inf];
                        app.LEditField.Editable = false;
                        app.REditField.Editable = true;
                        app.CEditField.Editable = true;

                        r=app.REditField.Value;
                       
                        app.CEditField.Value=1/(2*pi*r*app.CutofffrequencyEditField.Value);
                        app.LEditField.Value=0; 
                    end
                    if strcmp(value, 'Series RL Highpass filter') || strcmp(value, 'Series RL Lowpass filter')
                        app.Panel_9.Visible='off';

                        app.LEditField.Limits=[eps inf];
                        app.REditField.Limits=[eps inf];
                        app.CEditField.Limits=[0 inf];
                        app.LEditField.Editable = true;
                        app.REditField.Editable = true;
                        app.CEditField.Editable = false;

                        r=app.REditField.Value;
                       
                        app.LEditField.Value=r/(2*pi*app.CutofffrequencyEditField.Value);
                        app.CEditField.Value=0; 
                    end
                    if strcmp(value, 'Series RLC Bandpass filter') || strcmp(value, 'Series RLC Bandstop filter')
                        app.Panel_9.Visible='on';

                        app.LEditField.Limits=[eps inf];
                        app.REditField.Limits=[eps inf];
                        app.CEditField.Limits=[eps inf];
                        app.LEditField.Editable = true;
                        app.REditField.Editable = true;
                        app.CEditField.Editable = true;

                        r=app.REditField.Value;
                        w1=2*pi*app.lowercutofffreqEditField.Value;
                        w2=2*pi*app.HighercutofffreqEditField.Value;
                        l=r/(w2-w1);
                        app.LEditField.Value=l;
                        c=4/(l*(w1+w2).^2-(r.^2)/l);
                        app.CEditField.Value=c;
                    end
                    if strcmp(value,'Parallel RLC Bandpass filter') || strcmp(value,'Parallel RLC Bandstop filter')
                        app.Panel_9.Visible='on';
                        app.LEditField.Limits=[eps inf];
                        app.REditField.Limits=[eps inf];
                        app.CEditField.Limits=[eps inf];
                        app.LEditField.Editable = true;
                        app.REditField.Editable = true;
                        app.CEditField.Editable = true;

                        r=app.REditField.Value;
                        w1=2*pi*app.lowercutofffreqEditField.Value;
                        w2=2*pi*app.HighercutofffreqEditField.Value;
                        c=1/(r*(w2-w1));
                        l=4/(c*(((w1+w2)^2)-(r*c)^(-2)));
                        app.LEditField.Value=l
                        app.CEditField.Value=c; 
                    end
            
        end

        % Value changed function: CutofffrequencyEditField
        function CutofffrequencyEditFieldValueChanged(app, event)
            tmp=app.CutofffrequencyEditField.Value;
            app.CutofffrequencyEditField_2.Value=tmp;
            FilltersDropDownValueChanged(app, event);
          
        end

        % Value changed function: CutofffrequencyEditField_2
        function CutofffrequencyEditField_2ValueChanged(app, event)
            
            tmp=app.CutofffrequencyEditField_2.Value;
            app.CutofffrequencyEditField.Value=tmp;

            FilltersDropDownValueChanged(app, event)
            inputandoutputsignalButtonPushed(app, event);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value=app.FilltersDropDown.Value;
            if strcmp(value, 'Series RL Highpass filter') || strcmp(value, 'Series RL Lowpass filter')
            l = app.LEditField.Value;
            
            r=l*2*pi*app.CutofffrequencyEditField.Value;
            app.REditField.Value=r;
            c=0;
            app.CEditField.Value=c;
            end
            if strcmp(value, 'Series RLC Bandpass filter') || strcmp(value, 'Series RLC Bandstop filter')
                w1=2*pi*app.lowercutofffreqEditField.Value;
                w2=2*pi*app.HighercutofffreqEditField.Value;
                l=app.LEditField.Value;
                r=l*(w2-w1);
                app.REditField.Value=r;
                c=4/(l*(w1+w2).^2-(r.^2)/l);
                app.CEditField.Value=c;
            end
            if strcmp(value, 'Parallel RLC Bandpass filter') || strcmp(value, 'Parallel RLC Bandstop filter')
                w1=2*pi*app.lowercutofffreqEditField.Value;
                w2=2*pi*app.HighercutofffreqEditField.Value;
                l=app.LEditField.Value;
                c=1/(w1*w2*l);
                r=1/(c*(w2-w1));
                app.REditField.Value=r;
                app.CEditField.Value=c;
            end
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            value=app.FilltersDropDown.Value;
            if strcmp(value, 'Series RC Highpass filter') || strcmp(value, 'Series RC Lowpass filter')
            c = app.CEditField.Value;
            
            r=1/(2*pi*app.CutofffrequencyEditField.Value*c);
            app.REditField.Value=r;
            l=0;
            app.LEditField.Value=l;
            end
            if strcmp(value, 'Series RLC Bandpass filter') || strcmp(value, 'Series RLC Bandstop filter')
                w1=2*pi*app.lowercutofffreqEditField.Value;
                w2=2*pi*app.HighercutofffreqEditField.Value;
                c=app.CEditField.Value;
                l=1/(c*w1*w2);
                r=l*(w2-w1);
                app.LEditField.Value=l;
                app.REditField.Value=r;
            end
            if strcmp(value, 'Parallel RLC Bandpass filter') || strcmp(value, 'Parallel RLC Bandstop filter')
                w1=2*pi*app.lowercutofffreqEditField.Value;
                w2=2*pi*app.HighercutofffreqEditField.Value;
                c=app.CEditField.Value;
                l=1/(w1*w2*c);
                r=1/(c*(w2-w1));
                app.REditField.Value=r;
                app.LEditField.Value=l;
            end
        end

        % Callback function: HomeButton_bode, HomeButton_sinusoid, 
        % ...and 8 other components
        function HomeButton_bodePushed(app, event)

            % Get the absolute path to app's folder
            appFolder = fileparts(mfilename('fullpath'));

            app.Image2.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            app.Image2_2.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            app.Image2_3.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            app.Image2_4.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            app.Image2_5.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            app.Image2_6.ImageSource = fullfile(appFolder,'resources','home_inverted.png');
            pause(0.1);
            app.Image2.ImageSource = fullfile(appFolder,'resources','home_light.png');
            app.Image2_2.ImageSource = fullfile(appFolder,'resources','home_light.png');
            app.Image2_3.ImageSource = fullfile(appFolder,'resources','home_light.png');
            app.Image2_4.ImageSource = fullfile(appFolder,'resources','home_light.png');
            app.Image2_5.ImageSource = fullfile(appFolder,'resources','home_light.png');
            app.Image2_6.ImageSource = fullfile(appFolder,'resources','home_light.png');

            app.Panel_2.Visible="on";
            app.Panel_bodeplot.Visible="off";
            app.Panel_7.Visible="off";
            app.Panel_11.Visible='off';
            app.Panel_12.Visible='off';
            app.Panel_13.Visible='off';
            
        end

        % Callback function: DropDown, DropDown_2, amplitudeEditField, 
        % ...and 7 other components
        function inputandoutputsignalButtonPushed(app, event)
            app.Panel_7.Visible="on";
            app.Panel_2.Visible='off';
           text='sinusoid input';
           text1=app.DropDown_2.Value;

           if strcmp(text,'sinusoid input')
              
               app.Panel_sinusoidinput.Visible="on";

               if strcmp(text1,'range')
                   app.valuesEditField.Visible="off";
                   app.valuesEditFieldLabel.Visible="off";
                   app.incrementSpinner.Visible="on";
                   app.incrementSpinnerLabel.Visible="on";
                   app.firstEditField.Visible="on";
                   app.firstEditFieldLabel.Visible="on";
                   app.finalEditField.Visible="on";
                   app.finalEditFieldLabel.Visible="on";

                   n1=app.firstEditField.Value;
                   n2=app.finalEditField.Value;
                    if n1>n2
                        app.incrementSpinner.Limits=[(n2-n1) -1];
                    elseif n1==n2
                        app.incrementSpinner.Limits=[0 0];
                    elseif n1<n2
                        app.incrementSpinner.Limits=[1 (n2-n1)];
                    end
                    increment=app.incrementSpinner.Value;
                    if n1==n2
                        n=n1;
                    else
                        n=n1:increment:fix((n2-n1)/increment)*increment+n1;
                    end
               else 
                   app.valuesEditField.Visible="on";
                   app.valuesEditFieldLabel.Visible="on";
                   app.incrementSpinner.Visible="off";
                   app.incrementSpinnerLabel.Visible="off";
                   app.firstEditField.Visible="off";
                   app.firstEditFieldLabel.Visible="off";
                   app.finalEditField.Visible="off";
                   app.finalEditFieldLabel.Visible="off";

                   % Step 1: Get the string from the edit field
                    userStr = app.valuesEditField.Value;  % e.g., '1,2,3,55'

                    % Step 2: Replace commas with spaces (optional but helps str2num)
                    userStr = strrep(userStr, ',', ' ');
                    
                    % Step 3: Convert the string to a numeric vector
                    n = str2num(userStr);  % returns [1 2 3 55]


               end

               n=sort(n);

               f=app.frequencyofprincipalharmonicsEditField.Value;
               w=2*pi*f;
               ct=pi/(w*20*100);
               A=app.amplitudeEditField.Value;
               p=deg2rad(app.phaseangleEditField.Value);
               r=app.REditField.Value;
               c=app.CEditField.Value;
               l=app.LEditField.Value;
               ti=0:ct:2*pi/w;
               Vi=zeros(1,length(ti));
               Vo=zeros(1,length(ti));

               app.CenterfrequencyEditField.Value=1/(2*pi*sqrt(l*c));

               value=app.FilltersDropDown.Value;
               switch value
                   case {'Series RC Highpass filter','Series RC Lowpass filter',['S' ...
                           'eries RL Highpass filter'],'Series RL Lowpass filter'}
                       app.Panel_10.Visible="off";
                   otherwise
                       app.Panel_10.Visible="on";
               end

               if strcmp(app.FilltersDropDown.Value,'Series RC Highpass filter')
               b=1;
               
               elseif strcmp(app.FilltersDropDown.Value,'Series RC Lowpass filter')
               b=2;
               
               elseif strcmp(app.FilltersDropDown.Value,'Series RL Highpass filter')
               b=3;
              
               elseif strcmp(app.FilltersDropDown.Value,'Series RL Lowpass filter')
               b=4;
              

               elseif strcmp(app.FilltersDropDown.Value,'Series RLC Bandpass filter')
               b=5;
              
               elseif strcmp(app.FilltersDropDown.Value,'Series RLC Bandstop filter')
               b=6;
               
               elseif strcmp(app.FilltersDropDown.Value,'Parallel RLC Bandpass filter')
               b=7;
               
               elseif strcmp(app.FilltersDropDown.Value,'Parallel RLC Bandstop filter')
               b=8;
               
               end
                
                cla(app.UIAxes);
                cla(app.UIAxes2);
                Vo_f=zeros(1,length(ti));


               if strcmp(app.DropDown.Value,'individual signals')
                   app.Panel_sinusoidinput.Visible="on";
                   app.Panel_sinusoidinput_freqdomain_panel.Visible="off";
                   for k=n
                       
                        Vi=A*sin(w*ti*k+p);
                        plot(app.UIAxes,ti,Vi);
                        grid(app.UIAxes,'on');
                        hold(app.UIAxes,"on");

                    
                        if b==1 
                            z=(k*1i*w*r*c)./(1+k*1i*w*r*c);
                        elseif b==2
                            z=1./(1+k*1i*w*r*c); 
                        elseif b==3
                            z=k*1i*w*l./(r+k*1i*w*l);
                        elseif b==4
                            z=r./(r+k*1i*w*l);
                        elseif b==5
                            z=r./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==6
                            z=1i*(k*w*l-1./(k*w*c))./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==7
                            z=(1/r)./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        elseif b==8
                            z=1i*(k*w*c-1./(k*w*l))./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        end
                        % Vo_f=Vo_f+imag(z*A*exp(1i*(w*ti*k + p)));
                       
                        Vo = imag( z * A * exp(1i*(w*ti*k + p)));
                        plot(app.UIAxes2,ti,Vo);
                        ylim(app.UIAxes2, [-A A]);
                        grid(app.UIAxes2,'on');
                        hold(app.UIAxes2,'on');
                    end
               elseif(strcmp(app.DropDown.Value,'summation signals'))
                   app.Panel_sinusoidinput.Visible="on";
                   app.Panel_sinusoidinput_freqdomain_panel.Visible="off";
                   Vi=zeros(1,length(ti));
                   Vo=zeros(1,length(ti));
                   for k=n

                        Vi=Vi+A*sin(w*ti*k+p);
                    
                        if b==1 
                            z=(k*1i*w*r*c)./(1+k*1i*w*r*c);
                        elseif b==2
                            z=1./(1+k*1i*w*r*c); 
                        elseif b==3
                            z=k*1i*w*l./(r+k*1i*w*l);
                        elseif b==4
                            z=r./(r+k*1i*w*l);
                        elseif b==5
                            z=r./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==6
                            z=1i*(k*w*l-1./(k*w*c))./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==7
                            z=(1/r)./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        elseif b==8
                            z=1i*(k*w*c-1./(k*w*l))./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        end
                        
                        % Vo_f=Vo_f+imag(z*A*exp(1i*(w*ti*k + p)));
                        Vo=Vo+imag( z * A * exp(1i*(w*ti*k + p)) );
                   end
                    plot(app.UIAxes,ti,Vi);
                    grid(app.UIAxes,'on');
                    hold(app.UIAxes,"on");

                    plot(app.UIAxes2,ti,Vo);
                    ylim(app.UIAxes2, [min(Vi) max(Vi)]);
                    grid(app.UIAxes2,'on');
                    hold(app.UIAxes2,'on');
               
               else %frequency domain
                    app.Panel_sinusoidinput.Visible="off";
                    app.Panel_sinusoidinput_freqdomain_panel.Visible="on";
                    
                  
                    k=100;
                    dt = 1 / (k * max(n) * f);    % where k = 20, 50, 100...
                    % dt=pi/(w*20*10);
                    T=(sym(1)./(n*f));
                    
                    %Compute LCM step by step
                    lcm_val = T(1);
                    for k = 2:length(T)
                        lcm_val = lcm(lcm_val,T(k));
                    end
                    lcm_val=double(lcm_val);
                    
                    to = 0:dt:lcm_val;
                    Vi=zeros(1,length(to));
                    Vo=zeros(1,length(to));

                   for k=n
                        Vi=Vi+A*sin(w*to*k+p);
                    
                        if b==1 
                            z=(k*1i*w*r*c)./(1+k*1i*w*r*c);
                        elseif b==2
                            z=1./(1+k*1i*w*r*c); 
                        elseif b==3
                            z=k*1i*w*l./(r+k*1i*w*l);
                        elseif b==4
                            z=r./(r+k*1i*w*l);
                        elseif b==5
                            z=r./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==6
                            z=1i*(k*w*l-1./(k*w*c))./(r+1i*(k*w*l-1./(k*w*c)));
                        elseif b==7
                            z=(1/r)./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        elseif b==8
                            z=1i*(k*w*c-1./(k*w*l))./((1/r)+1i*(k*w*c-1./(k*w*l)));
                        end
                        
                        % Vo_f=Vo_f+imag(z*A*exp(1i*(w*to*k + p)));
                        Vo=Vo+imag( z * A * exp(1i*(w*to*k + p)) );
                   end


                        % Sampling frequency
                        fs = (1/dt);             % Example: 1000 Hz
                        N = lcm_val/dt;        % Number of samples
                        k1=(N*max(n)*f)/fs +1 ;%index of max frequency of the signal(harmonics) in fbin
                        % Frequency vector (in Hz)
                        fbin = (0:k1-1)*(fs/N);     % Frequency bins

                        
                        % Compute FFT for input signal
                        Y = fft(Vi);
                        Vi_fft = abs(Y(1:k1));    % One-sided magnitude
                        Vi_fft = (2/N) * Vi_fft;   % Proper scaling for real input
                        

                        % Create mask for exact harmonics
                        harmonic_freqs = n*f;
                        show_freqs = false(size(fbin));
                        tolerance =.1; % Frequency tolerance in Hz
                        
                        for k = 1:length(fbin)
                            if any(abs(fbin(k) - harmonic_freqs) < tolerance)
                                show_freqs(k) = true;
                            end
                        end


                        % Plot using stem
                        stem(app.UIAxes_2, fbin(show_freqs),Vi_fft(show_freqs),'filled');
                        grid(app.UIAxes_2, 'on');       % Enable grid
    
                        % Compute FFT for output signal
                        Y = fft(Vo);
                        Vo_fft = abs(Y(1:k1));    % One-sided magnitude
                        Vo_fft = (2/N) * Vo_fft;   % Proper scaling for real input
                        

                        % Plot using stem
                        stem(app.UIAxes2_2, fbin(show_freqs), Vo_fft(show_freqs), 'filled');
                        ylims = ylim(app.UIAxes_2);      % Get current y-limits of app.UIAxes2
                        n1 = ylims(2); 
                        ylim(app.UIAxes2_2,[0,n1]);
                        grid(app.UIAxes2_2, 'on');       % Enable grid


               end
            signal_str = string(repmat(char(160), 1, 2));  % 3 non-breaking spaces as a string
            for i =1:(min(length(n),3))
                if i==1
                    signal_str = signal_str+sprintf("Asin(%d*wt)", n(i));
                else
                    signal_str = signal_str + " + " + sprintf("Asin(%d*wt)", n(i));
                end
            end
            if ~(length(n) == 1)
            signal_str = signal_str + " + " + "......";
            end
            app.Label.Text=signal_str;
           
        end
        end

        % Value changed function: HighercutofffreqEditField, 
        % ...and 1 other component
        function lowercutofffreqEditFieldValueChanged(app, event)

            if app.lowercutofffreqEditField.Value>app.HighercutofffreqEditField.Value
                c=app.lowercutofffreqEditField.Value;
                app.lowercutofffreqEditField.Value=app.HighercutofffreqEditField.Value;
                app.HighercutofffreqEditField.Value=c;
            end
            tmp1=app.lowercutofffreqEditField.Value;
            tmp2=app.HighercutofffreqEditField.Value;
            app.lowercutofffreqEditField_2.Value=tmp1;
            app.HighercutofffreqEditField_2.Value=tmp2;

            FilltersDropDownValueChanged(app, event);
            
        end

        % Value changed function: HighercutofffreqEditField_2, 
        % ...and 1 other component
        function lowercutofffreqEditField_2ValueChanged(app, event)
            if app.lowercutofffreqEditField_2.Value>app.HighercutofffreqEditField_2.Value
                c=app.lowercutofffreqEditField_2.Value;
                app.lowercutofffreqEditField_2.Value=app.HighercutofffreqEditField_2.Value;
                app.HighercutofffreqEditField_2.Value=c;
            end
            tmp1=app.lowercutofffreqEditField_2.Value;
            tmp2=app.HighercutofffreqEditField_2.Value;
            app.lowercutofffreqEditField.Value=tmp1;
            app.HighercutofffreqEditField.Value=tmp2;

            FilltersDropDownValueChanged(app, event);
            inputandoutputsignalButtonPushed(app, event);
            
        end

        % Value changed function: FilltersDropDown_bode_panel
        function FilltersDropDown_bode_panelValueChanged(app, event)
            app.FilltersDropDown.Value=app.FilltersDropDown_bode_panel.Value;
            FilltersDropDownValueChanged(app, event);
            BodeplotPhaseplotsButtonPushed(app, event);
        end

        % Value changed function: FilltersDropDown_inoutput_panel
        function FilltersDropDown_inoutput_panelValueChanged(app, event)
            app.FilltersDropDown.Value=app.FilltersDropDown_inoutput_panel.Value;
            FilltersDropDownValueChanged(app, event);
             inputandoutputsignalButtonPushed(app, event)
        end

        % Button pushed function: SchematicsButton
        function SchematicsButtonPushed(app, event)
           app.Panel_11.Visible= "on";
           value=app.FilltersDropDown_sch_panel.Value;

            % Get the absolute path to app's folder
            appFolder = fileparts(mfilename('fullpath'));

           if strcmp(value,'Series RC Highpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'rchp.png');
           elseif strcmp(value,'Series RC Lowpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'rclp.png');
           elseif strcmp(value,'Series RL Highpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'rlhp.png');
           elseif strcmp(value,'Series RL Lowpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'rllp.png');
           elseif strcmp(value,'Series RLC Bandpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'srlcbp.png');
           elseif strcmp(value,'Series RLC Bandstop filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'srlcbs.png');
           elseif strcmp(value,'Parallel RLC Bandpass filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'prlcbp.png');
           elseif strcmp(value,'Parallel RLC Bandstop filter')
           app.Image.ImageSource = fullfile(appFolder,'resources', 'prlcbs.png');
           end

           app.REditField_2.Value=app.REditField.Value;
           app.LEditField_2.Value=app.LEditField.Value;
           app.CEditField_2.Value=app.CEditField.Value;
        end

        % Value changed function: REditField_2
        function REditField_2ValueChanged(app, event)

            value = app.REditField_2.Value;
            app.REditField.Value=value;
            FilltersDropDownValueChanged(app, event);
            app.LEditField_2.Value=app.LEditField.Value
            app.CEditField_2.Value=app.CEditField.Value;
        end

        % Value changed function: LEditField_2
        function LEditField_2ValueChanged(app, event)

            value1 = app.LEditField_2.Value;
            app.LEditField.Value=value1;
            LEditFieldValueChanged(app, event);
            app.REditField_2.Value=app.REditField.Value;
            app.CEditField_2.Value=app.CEditField.Value;
        end

        % Value changed function: CEditField_2
        function CEditField_2ValueChanged(app, event)

            value = app.CEditField_2.Value;
            app.CEditField.Value=value;
            CEditFieldValueChanged(app, event);
            app.REditField_2.Value=app.REditField.Value;
            app.LEditField_2.Value=app.LEditField.Value;
            
        end

        % Value changed function: FilltersDropDown_sch_panel
        function FilltersDropDown_sch_panelValueChanged(app, event)

            app.FilltersDropDown.Value=app.FilltersDropDown_sch_panel.Value;
            FilltersDropDownValueChanged(app, event);
            SchematicsButtonPushed(app, event)
        end

        % Button pushed function: DifferentcalculationsButton
        function DifferentcalculationsButtonPushed(app, event)
                    app.Panel_2.Visible="off";
                    app.Panel_12.Visible="on";

                    r=app.REditField.Value;
                    c=app.CEditField.Value;
                     l=app.LEditField.Value;

                    fc=app.CutofffrequencyEditField.Value;
                    fc_L=app.lowercutofffreqEditField.Value;
                    fc_h=app.HighercutofffreqEditField.Value;
                    fcenter=1/(2*pi*sqrt(l*c));

                    value=app.FilltersDropDown_cal_panel.Value;
                    
                    B="f";
                    Q="f";
                    Att_rate="f";
                    filter_order="f";
                    time_const="f";
                    switch value
                        case 'Series RC Highpass filter' 
                            B='inf';Q='N/A';
                            Att_rate="+20dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='1st order';
                            time_const=num2str(r*c);

                        case 'Series RC Lowpass filter'
                            B=num2str(fc)+" Hz";Q='N/A';
                            Att_rate="-20dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='1st order';
                            time_const=num2str(r*c);
                  
                        case 'Series RL Highpass filter'
                            B="inf";Q='N/A';
                            Att_rate="+20dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='1st order';
                            time_const=num2str(l/r);
                      
                        case 'Series RL Lowpass filter'
                            B=num2str(fc)+" Hz";Q='N/A';
                            Att_rate="-20dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='1st order';
                            time_const=num2str(l/r);
                   
                        case 'Series RLC Bandpass filter'
                            B=num2str(fc_h-fc_L)+" Hz";Q=num2str(fcenter/(fc_h-fc_L));
                            Att_rate="40dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='2nd order';
                            time_const="N/A";
                        
                        case 'Series RLC Bandstop filter'
                            B=num2str(fc_h-fc_L)+" Hz";Q=num2str(fcenter/(fc_h-fc_L));
                            Att_rate="40dB/decade";
                            app.outsidepassbandLabel.Text="(within the stopband)";
                            filter_order='2nd order';
                            time_const="N/A";
                           
                        case 'Parallel RLC Bandpass filter'
                            B=num2str(fc_h-fc_L)+" Hz";Q=num2str(fcenter/(fc_h-fc_L));
                            Att_rate="40dB/decade";
                            app.outsidepassbandLabel.Text="(outside passband)";
                            filter_order='2nd order';
                            time_const="N/A";
                        
                        case 'Parallel RLC Bandstop filter'
                            B=num2str(fc_h-fc_L)+" Hz";Q=num2str(fcenter/(fc_h-fc_L));
                            Att_rate="40dB/decade";
                            app.outsidepassbandLabel.Text="(within the stopband)";
                            filter_order='2nd order';
                            time_const="N/A";
                    end
                    app.BandwidthEditField.Value=B;
                    app.QualityfactorEditField.Value=Q;
                    app.AttenuationRateEditField.Value=Att_rate;
                    app.FilterOrderEditField.Value=filter_order;
                    app.TimeConstantEditField.Value=time_const;
            
        end

        % Value changed function: FilltersDropDown_cal_panel
        function FilltersDropDown_cal_panelValueChanged(app, event)
            app.FilltersDropDown.Value=app.FilltersDropDown_cal_panel.Value;
            FilltersDropDownValueChanged(app, event);
            DifferentcalculationsButtonPushed(app, event)
            
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            app.Panel_2.Visible="off";
            app.Panel_13.Visible="on";
            
         
        end

        % Image clicked function: Image7
        function ReseteverythingButtonPushed(app, event)
                    % Store the class name of the app
            appClass = class(app);
        
            % Delete the current app instance
            delete(app);
        
            % Reopen a new instance (restart)
            feval(appClass);  % This creates a new instance of the app
            
        end

        % Image clicked function: Image10
        function Image10Clicked(app, event)
            web('https://github.com/Fahim-Shahriyar117/passive-filter-project', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image9 = uiimage(app.Panel_2);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'resources', 'main_bg.png');
            app.LEditFieldLabel = uilabel(app.Panel_2);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Panel_2, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.CEditFieldLabel = uilabel(app.Panel_2);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.Panel_2, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.CEditField.Value = 1e-15;
            app.SchematicsButton = uibutton(app.Panel_2, 'push');
            app.SchematicsButton.ButtonPushedFcn = createCallbackFcn(app, @SchematicsButtonPushed, true);
            app.SchematicsButton.Text = 'Schematics';
            app.BodeplotPhaseplotsButton = uibutton(app.Panel_2, 'push');
            app.BodeplotPhaseplotsButton.ButtonPushedFcn = createCallbackFcn(app, @BodeplotPhaseplotsButtonPushed, true);
            app.BodeplotPhaseplotsButton.Text = 'Bode plot & Phase plots';
            app.DifferentcalculationsButton = uibutton(app.Panel_2, 'push');
            app.DifferentcalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentcalculationsButtonPushed, true);
            app.DifferentcalculationsButton.Text = 'Different calculations';
            app.inputandoutputsignalButton = uibutton(app.Panel_2, 'push');
            app.inputandoutputsignalButton.ButtonPushedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.inputandoutputsignalButton.Text = 'input and output signal';
            app.FilterTypeSelectionLabel = uilabel(app.Panel_2);
            app.FilterTypeSelectionLabel.Text = 'Filter Type Selection';
            app.FilltersDropDown = uidropdown(app.Panel_2);
            app.FilltersDropDown.Items = {'Series RC Highpass filter', 'Series RC Lowpass filter', 'Series RL Highpass filter', 'Series RL Lowpass filter', 'Series RLC Bandpass filter', 'Series RLC Bandstop filter', 'Parallel RLC Bandpass filter', 'Parallel RLC Bandstop filter'};
            app.FilltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDownValueChanged, true);
            app.FilltersDropDown.Value = 'Series RC Highpass filter';
            app.CutofffrequencyEditFieldLabel = uilabel(app.Panel_2);
            app.CutofffrequencyEditFieldLabel.Text = 'Cutoff frequency';
            app.CutofffrequencyEditField = uieditfield(app.Panel_2, 'numeric');
            app.CutofffrequencyEditField.Limits = [1e-15 Inf];
            app.CutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutofffrequencyEditFieldValueChanged, true);
            app.CutofffrequencyEditField.Value = 1000;
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = 'Hz';
            app.Panel_9 = uipanel(app.Panel_2);
            app.Panel_9.Visible = 'off';
            app.HighercutofffreqEditField_3Label = uilabel(app.Panel_9);
            app.HighercutofffreqEditField_3Label.Text = 'Higher cutoff freq.';
            app.HighercutofffreqEditField = uieditfield(app.Panel_9, 'numeric');
            app.HighercutofffreqEditField.Limits = [1e-15 Inf];
            app.HighercutofffreqEditField.ValueChangedFcn = createCallbackFcn(app, @lowercutofffreqEditFieldValueChanged, true);
            app.HighercutofffreqEditField.Value = 100;
            app.HzLabel_2 = uilabel(app.Panel_9);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.Panel_9);
            app.HzLabel_3.Text = 'Hz';
            app.lowercutofffreqEditField_3Label = uilabel(app.Panel_9);
            app.lowercutofffreqEditField_3Label.Text = 'lower cutoff freq.';
            app.lowercutofffreqEditField = uieditfield(app.Panel_9, 'numeric');
            app.lowercutofffreqEditField.Limits = [1e-15 Inf];
            app.lowercutofffreqEditField.ValueChangedFcn = createCallbackFcn(app, @lowercutofffreqEditFieldValueChanged, true);
            app.lowercutofffreqEditField.Value = 80;
            app.Image4 = uiimage(app.Panel_2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'resources', 'matlab.png');
            app.REditFieldLabel = uilabel(app.Panel_2);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Panel_2, 'numeric');
            app.REditField.Limits = [1e-15 Inf];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDownValueChanged, true);
            app.REditField.Value = 1000;
            app.Image6 = uiimage(app.Panel_2);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'resources', 'info.png');
            app.Image7 = uiimage(app.Panel_2);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @ReseteverythingButtonPushed, true);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'resources', 'reset.png');
            app.Panel_bodeplot = uipanel(app.UIFigure);
            app.HomeButton_bode = uibutton(app.Panel_bodeplot, 'push');
            app.HomeButton_bode.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.HomeButton_bode.Visible = 'off';
            app.HomeButton_bode.Text = 'Home';
            app.Panel_6 = uipanel(app.Panel_bodeplot);
            app.UIAxes_M = uiaxes(app.Panel_6);
            xlabel(app.UIAxes_M, 'frequency(Hz)')
            ylabel(app.UIAxes_M, 'Gain')
            zlabel(app.UIAxes_M, 'Z')
            app.MagnitudeplotLabel = uilabel(app.Panel_6);
            app.MagnitudeplotLabel.Text = 'Magnitude plot';
            app.Panel_4 = uipanel(app.Panel_bodeplot);
            app.UIAxes_P = uiaxes(app.Panel_4);
            xlabel(app.UIAxes_P, 'frequency(Hz)')
            ylabel(app.UIAxes_P, 'Phase')
            zlabel(app.UIAxes_P, 'Z')
            app.PhaseplotLabel = uilabel(app.Panel_4);
            app.PhaseplotLabel.Text = 'Phase plot';
            app.Panel_5 = uipanel(app.Panel_bodeplot);
            app.UIAxes_MDB = uiaxes(app.Panel_5);
            xlabel(app.UIAxes_MDB, 'frequency(Hz)')
            ylabel(app.UIAxes_MDB, 'Gain(DB)')
            zlabel(app.UIAxes_MDB, 'Z')
            app.MagnitudeplotDBLabel = uilabel(app.Panel_5);
            app.MagnitudeplotDBLabel.Text = 'Magnitude plot(DB)';
            app.FilltersDropDown_bode_panel = uidropdown(app.Panel_bodeplot);
            app.FilltersDropDown_bode_panel.Items = {'Series RC Highpass filter', 'Series RC Lowpass filter', 'Series RL Highpass filter', 'Series RL Lowpass filter', 'Series RLC Bandpass filter', 'Series RLC Bandstop filter', 'Parallel RLC Bandpass filter', 'Parallel RLC Bandstop filter'};
            app.FilltersDropDown_bode_panel.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDown_bode_panelValueChanged, true);
            app.FilltersDropDown_bode_panel.Value = 'Series RC Highpass filter';
            app.Image2 = uiimage(app.Panel_bodeplot);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.Panel_7 = uipanel(app.UIFigure);
            app.Panel_8 = uipanel(app.Panel_7);
            app.InputparametersLabel = uilabel(app.Panel_8);
            app.InputparametersLabel.Text = 'Input parameters';
            app.DropDown = uidropdown(app.Panel_8);
            app.DropDown.Items = {'individual signals', 'summation signals', 'frequency domain'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.DropDown.Value = 'individual signals';
            app.amplitudeEditFieldLabel = uilabel(app.Panel_8);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.Panel_8, 'numeric');
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.amplitudeEditField.Value = 10;
            app.phaseangleEditFieldLabel = uilabel(app.Panel_8);
            app.phaseangleEditFieldLabel.Text = 'phase angle';
            app.phaseangleEditField = uieditfield(app.Panel_8, 'numeric');
            app.phaseangleEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.HarmonicsLabel = uilabel(app.Panel_8);
            app.HarmonicsLabel.Text = 'Harmonics';
            app.DropDown_2 = uidropdown(app.Panel_8);
            app.DropDown_2.Items = {'range', 'value'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.DropDown_2.Value = 'range';
            app.incrementSpinnerLabel = uilabel(app.Panel_8);
            app.incrementSpinnerLabel.Text = 'increment';
            app.incrementSpinner = uispinner(app.Panel_8);
            app.incrementSpinner.RoundFractionalValues = 'on';
            app.incrementSpinner.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.firstEditFieldLabel = uilabel(app.Panel_8);
            app.firstEditFieldLabel.Text = 'first';
            app.firstEditField = uieditfield(app.Panel_8, 'numeric');
            app.firstEditField.Limits = [1 Inf];
            app.firstEditField.RoundFractionalValues = 'on';
            app.firstEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.firstEditField.Value = 1;
            app.finalEditFieldLabel = uilabel(app.Panel_8);
            app.finalEditFieldLabel.Text = 'final';
            app.finalEditField = uieditfield(app.Panel_8, 'numeric');
            app.finalEditField.Limits = [1 Inf];
            app.finalEditField.RoundFractionalValues = 'on';
            app.finalEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.finalEditField.Value = 1;
            app.CutofffrequencyEditField_2Label = uilabel(app.Panel_8);
            app.CutofffrequencyEditField_2Label.Text = 'Cutoff frequency';
            app.CutofffrequencyEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.CutofffrequencyEditField_2.Limits = [1e-15 Inf];
            app.CutofffrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @CutofffrequencyEditField_2ValueChanged, true);
            app.CutofffrequencyEditField_2.Value = 1000;
            app.valuesEditFieldLabel = uilabel(app.Panel_8);
            app.valuesEditFieldLabel.Visible = 'off';
            app.valuesEditFieldLabel.Text = 'values:';
            app.valuesEditField = uieditfield(app.Panel_8, 'text');
            app.valuesEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.valuesEditField.Visible = 'off';
            app.valuesEditField.Value = '1,5,10';
            app.frequencyofprincipalharmonicsEditFieldLabel = uilabel(app.Panel_8);
            app.frequencyofprincipalharmonicsEditFieldLabel.Text = 'frequency of principal harmonics';
            app.frequencyofprincipalharmonicsEditField = uieditfield(app.Panel_8, 'numeric');
            app.frequencyofprincipalharmonicsEditField.ValueChangedFcn = createCallbackFcn(app, @inputandoutputsignalButtonPushed, true);
            app.frequencyofprincipalharmonicsEditField.Value = 10;
            app.Panel_10 = uipanel(app.Panel_8);
            app.Panel_10.Visible = 'off';
            app.lowercutofffreqEditField_2Label = uilabel(app.Panel_10);
            app.lowercutofffreqEditField_2Label.Text = 'lower cutoff freq.';
            app.lowercutofffreqEditField_2 = uieditfield(app.Panel_10, 'numeric');
            app.lowercutofffreqEditField_2.Limits = [1e-15 Inf];
            app.lowercutofffreqEditField_2.ValueChangedFcn = createCallbackFcn(app, @lowercutofffreqEditField_2ValueChanged, true);
            app.lowercutofffreqEditField_2.Value = 80;
            app.HighercutofffreqEditField_2Label = uilabel(app.Panel_10);
            app.HighercutofffreqEditField_2Label.Text = 'Higher cutoff freq.';
            app.HighercutofffreqEditField_2 = uieditfield(app.Panel_10, 'numeric');
            app.HighercutofffreqEditField_2.Limits = [1e-15 Inf];
            app.HighercutofffreqEditField_2.ValueChangedFcn = createCallbackFcn(app, @lowercutofffreqEditField_2ValueChanged, true);
            app.HighercutofffreqEditField_2.Value = 100;
            app.CenterfrequencyEditFieldLabel = uilabel(app.Panel_10);
            app.CenterfrequencyEditFieldLabel.Text = 'Center frequency';
            app.CenterfrequencyEditField = uieditfield(app.Panel_10, 'numeric');
            app.CenterfrequencyEditField.Limits = [1e-15 Inf];
            app.CenterfrequencyEditField.Editable = 'off';
            app.CenterfrequencyEditField.Value = 100;
            app.Panel_sinusoidinput = uipanel(app.Panel_7);
            app.UIAxes = uiaxes(app.Panel_sinusoidinput);
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Xi(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Panel_sinusoidinput);
            xlabel(app.UIAxes2, 'time(s)')
            ylabel(app.UIAxes2, 'Xo(t)')
            zlabel(app.UIAxes2, 'Z')
            app.InputLabel = uilabel(app.Panel_sinusoidinput);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.Panel_sinusoidinput);
            app.OutputLabel.Text = 'Output';
            app.HomeButton_sinusoid = uibutton(app.Panel_sinusoidinput, 'push');
            app.HomeButton_sinusoid.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.HomeButton_sinusoid.Visible = 'off';
            app.HomeButton_sinusoid.Text = 'Home';
            app.Image2_2 = uiimage(app.Panel_sinusoidinput);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.FilltersDropDown_inoutput_panel = uidropdown(app.Panel_7);
            app.FilltersDropDown_inoutput_panel.Items = {'Series RC Highpass filter', 'Series RC Lowpass filter', 'Series RL Highpass filter', 'Series RL Lowpass filter', 'Series RLC Bandpass filter', 'Series RLC Bandstop filter', 'Parallel RLC Bandpass filter', 'Parallel RLC Bandstop filter'};
            app.FilltersDropDown_inoutput_panel.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDown_inoutput_panelValueChanged, true);
            app.FilltersDropDown_inoutput_panel.Value = 'Series RC Highpass filter';
            app.Label = uilabel(app.Panel_7);
            app.Label.Text = '            Label';
            app.XitLabel = uilabel(app.Panel_7);
            app.XitLabel.Text = 'Xi(t) =';
            app.Panel_sinusoidinput_freqdomain_panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_sinusoidinput_freqdomain_panel);
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, '|Xi(f)|')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Panel_sinusoidinput_freqdomain_panel);
            xlabel(app.UIAxes2_2, 'Frequency (Hz)')
            ylabel(app.UIAxes2_2, '|Xo(f)|')
            zlabel(app.UIAxes2_2, 'Z')
            app.InputLabel_2 = uilabel(app.Panel_sinusoidinput_freqdomain_panel);
            app.InputLabel_2.Text = 'Input';
            app.OutputLabel_2 = uilabel(app.Panel_sinusoidinput_freqdomain_panel);
            app.OutputLabel_2.Text = 'Output';
            app.HomeButton_sinusoid_3 = uibutton(app.Panel_sinusoidinput_freqdomain_panel, 'push');
            app.HomeButton_sinusoid_3.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.HomeButton_sinusoid_3.Visible = 'off';
            app.HomeButton_sinusoid_3.Text = 'Home';
            app.Image2_4 = uiimage(app.Panel_sinusoidinput_freqdomain_panel);
            app.Image2_4.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.Panel_11 = uipanel(app.UIFigure);
            app.Panel_sinusoidinput_2 = uipanel(app.Panel_11);
            app.HomeButton_sinusoid_4 = uibutton(app.Panel_sinusoidinput_2, 'push');
            app.HomeButton_sinusoid_4.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.HomeButton_sinusoid_4.Visible = 'off';
            app.HomeButton_sinusoid_4.Text = 'Home';
            app.REditField_2Label = uilabel(app.Panel_sinusoidinput_2);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.Panel_sinusoidinput_2, 'numeric');
            app.REditField_2.Limits = [1e-15 Inf];
            app.REditField_2.ValueChangedFcn = createCallbackFcn(app, @REditField_2ValueChanged, true);
            app.REditField_2.Value = 1000;
            app.LEditField_2Label = uilabel(app.Panel_sinusoidinput_2);
            app.LEditField_2Label.Text = 'L';
            app.LEditField_2 = uieditfield(app.Panel_sinusoidinput_2, 'numeric');
            app.LEditField_2.ValueChangedFcn = createCallbackFcn(app, @LEditField_2ValueChanged, true);
            app.CEditField_2Label = uilabel(app.Panel_sinusoidinput_2);
            app.CEditField_2Label.Text = 'C';
            app.CEditField_2 = uieditfield(app.Panel_sinusoidinput_2, 'numeric');
            app.CEditField_2.ValueChangedFcn = createCallbackFcn(app, @CEditField_2ValueChanged, true);
            app.CEditField_2.Value = 1e-15;
            app.Image = uiimage(app.Panel_sinusoidinput_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'rchp.png');
            app.Image2_3 = uiimage(app.Panel_sinusoidinput_2);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.FilltersDropDown_sch_panel = uidropdown(app.Panel_11);
            app.FilltersDropDown_sch_panel.Items = {'Series RC Highpass filter', 'Series RC Lowpass filter', 'Series RL Highpass filter', 'Series RL Lowpass filter', 'Series RLC Bandpass filter', 'Series RLC Bandstop filter', 'Parallel RLC Bandpass filter', 'Parallel RLC Bandstop filter'};
            app.FilltersDropDown_sch_panel.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDown_sch_panelValueChanged, true);
            app.FilltersDropDown_sch_panel.Value = 'Series RC Highpass filter';
            app.ENDButton = uibutton(app.UIFigure, 'push');
            app.ENDButton.Text = 'END';
            app.Panel_12 = uipanel(app.UIFigure);
            app.Panel_sinusoidinput_3 = uipanel(app.Panel_12);
            app.HomeButton_sinusoid_5 = uibutton(app.Panel_sinusoidinput_3, 'push');
            app.HomeButton_sinusoid_5.Visible = 'off';
            app.HomeButton_sinusoid_5.Text = 'Home';
            app.Image2_5 = uiimage(app.Panel_sinusoidinput_3);
            app.Image2_5.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.BandwidthEditFieldLabel = uilabel(app.Panel_sinusoidinput_3);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth:';
            app.BandwidthEditField = uieditfield(app.Panel_sinusoidinput_3, 'text');
            app.BandwidthEditField.Editable = 'off';
            app.QualityfactorEditFieldLabel = uilabel(app.Panel_sinusoidinput_3);
            app.QualityfactorEditFieldLabel.Text = 'Quality factor:';
            app.QualityfactorEditField = uieditfield(app.Panel_sinusoidinput_3, 'text');
            app.QualityfactorEditField.Editable = 'off';
            app.AttenuationRateEditFieldLabel = uilabel(app.Panel_sinusoidinput_3);
            app.AttenuationRateEditFieldLabel.Text = 'Attenuation Rate:';
            app.AttenuationRateEditField = uieditfield(app.Panel_sinusoidinput_3, 'text');
            app.AttenuationRateEditField.Editable = 'off';
            app.FilterOrderEditFieldLabel = uilabel(app.Panel_sinusoidinput_3);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order:';
            app.FilterOrderEditField = uieditfield(app.Panel_sinusoidinput_3, 'text');
            app.FilterOrderEditField.Editable = 'off';
            app.TimeConstantEditFieldLabel = uilabel(app.Panel_sinusoidinput_3);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant:';
            app.TimeConstantEditField = uieditfield(app.Panel_sinusoidinput_3, 'text');
            app.TimeConstantEditField.Editable = 'off';
            app.outsidepassbandLabel = uilabel(app.Panel_sinusoidinput_3);
            app.outsidepassbandLabel.Text = '(outside passband)';
            app.FilltersDropDown_cal_panel = uidropdown(app.Panel_12);
            app.FilltersDropDown_cal_panel.Items = {'Series RC Highpass filter', 'Series RC Lowpass filter', 'Series RL Highpass filter', 'Series RL Lowpass filter', 'Series RLC Bandpass filter', 'Series RLC Bandstop filter', 'Parallel RLC Bandpass filter', 'Parallel RLC Bandstop filter'};
            app.FilltersDropDown_cal_panel.ValueChangedFcn = createCallbackFcn(app, @FilltersDropDown_cal_panelValueChanged, true);
            app.FilltersDropDown_cal_panel.Value = 'Series RC Highpass filter';
            app.Panel_13 = uipanel(app.UIFigure);
            app.Image8 = uiimage(app.Panel_13);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'resources', 'test.png');
            app.Image2_6 = uiimage(app.Panel_13);
            app.Image2_6.ImageClickedFcn = createCallbackFcn(app, @HomeButton_bodePushed, true);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'resources', 'home.png');
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel.Text = 'Project Name: ';
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_2 = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_2.Text = {'Passive filters with Input taken from Users and showing '; 'Frequency Domain Behavior of all Processed Signals'};
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_3 = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_3.Text = 'Group members:';
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_4 = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_4.Text = {'2206111:Md. Mahedi Hasan Saikot'; '2206112:Md. Sakib AL Hasan'; '2206113:Md. Ikrammel Haque Mridul'; '2206114:Fahim Shahriyar'};
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_5 = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_5.Text = 'Group:04';
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_6 = uilabel(app.Panel_13);
            app.ProjectNamePassivefilterswithInputtakenfromUsersandLabel_6.Text = 'Link:';
            app.Image10 = uiimage(app.Panel_13);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image10Clicked, true);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'resources', 'github-sign.png');
            app.Panel_3 = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = passive_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end